classdef CognitiveRadioDashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TechnologyPanel                matlab.ui.container.Panel
        AIRECOMMENDATIONTextArea       matlab.ui.control.TextArea
        AIRECOMMENDATIONTextAreaLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        SCANButton                     matlab.ui.control.Button
        TechnologyDropDown             matlab.ui.control.DropDown
        TechnologyDropDownLabel        matlab.ui.control.Label
        StatusReadyLabel               matlab.ui.control.Label
        EXITButton                     matlab.ui.control.Button
        SignalVisualizationPanel       matlab.ui.container.Panel
        RESETButton                    matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        AIPREDICTIONPanel              matlab.ui.container.Panel
        PredictionTextArea             matlab.ui.control.TextArea
        PredictionTextAreaLabel        matlab.ui.control.Label
        PREDICTIONRESULTLabel          matlab.ui.control.Label
        COMPARESIGNALSPanel            matlab.ui.container.Panel
        COMPARETYPEDropDown            matlab.ui.control.DropDown
        COMPARETYPELabel               matlab.ui.control.Label
        COMPARESIGNALSButton           matlab.ui.control.Button
        COMPARESIGNAL2DropDown         matlab.ui.control.DropDown
        COMPARESIGNAL2DropDownLabel    matlab.ui.control.Label
        COMPARESIGNAL1DropDown         matlab.ui.control.DropDown
        COMPARESIGNAL1DropDownLabel    matlab.ui.control.Label
        SIGNALPARAMETERPanel           matlab.ui.container.Panel
        PARAMETERBOXTextArea           matlab.ui.control.TextArea
        PARAMETERBOXTextAreaLabel      matlab.ui.control.Label
        DISPLAYGRAPHPanel              matlab.ui.container.Panel
        SHOWGRAPHButton                matlab.ui.control.Button
        GRAPHLISTBOXListBox            matlab.ui.control.ListBox
        Label                          matlab.ui.control.Label
        SIGNALTYPEPanel                matlab.ui.container.Panel
        PREDICTSIGNALButton            matlab.ui.control.Button
        GENERATESIGNALButton           matlab.ui.control.Button
        SignalDropDown                 matlab.ui.control.DropDown
        SignalDropDownLabel            matlab.ui.control.Label
        AICONGNITIVERADIOSYSTEMLabel   matlab.ui.control.Label
    end

    properties (Access = private)
        SignalData
        AIModel
        FeatureMin
        FeatureMax
    end

    methods (Access = private)
        
        function loadModel(app)
             projectRoot = 'C:\Users\SAI VIJAY VARDHAN\OneDrive\ドキュメント\MATLAB\AI_Cognitive_Radio';

    addpath(genpath(projectRoot));

    modelFile = fullfile(projectRoot,'AI','SavedModel.mat');

    if ~isfile(modelFile)
        uialert(app.UIFigure,...
            'SavedModel.mat not found.',...
            'AI Model Error');
        return;
    end

    data = load(modelFile,...
        'Model',...
        'featureMin',...
        'featureMax');

    app.AIModel = data.Model;
    app.FeatureMin = data.featureMin;
    app.FeatureMax = data.featureMax;
     % Force both to 1 x 8
    app.FeatureMin = reshape(app.FeatureMin,1,[]);
    app.FeatureMax = reshape(app.FeatureMax,1,[]);
        end
         function SignalData = generateSelectedSignal(app, signalName)
          projectRoot = 'C:\Users\SAI VIJAY VARDHAN\OneDrive\ドキュメント\MATLAB\AI_Cognitive_Radio';

          addpath(genpath(projectRoot));
        switch signalName

            case 'BPSK'
                SignalData = generateBPSK();
                disp('==============================');
                disp('SignalData fields:');
                disp(fieldnames(app.SignalData));
            case 'QPSK'
                SignalData = generateQPSK();

            case 'AM'
                SignalData = generateAM();

            case 'FM'
                SignalData = generateFM();

            case 'QAM16'
                SignalData = generateQAM16();

            case 'OFDM'
                SignalData = generateOFDM();

            case 'Bluetooth'
                SignalData = generateBluetooth();

            case 'LTE'
                SignalData = generateLTE();

            case '5G'
                SignalData = generate5G();

            otherwise
                error('Unknown signal type: %s', signalName);

        end

         
         end
             % ==========================================
    % FFT PLOT FUNCTION
    % ==========================================
    function plotFFT(app, ax, SignalData)

        x = SignalData.signal;

        % Get sampling frequency
        if isfield(SignalData,'Fs') && ~isempty(SignalData.Fs)
            Fs = SignalData.Fs;
        else
            Fs = 1/(SignalData.time(2)-SignalData.time(1));
        end

        % Number of samples
        N = length(x);

        % FFT
        Y = fft(x);

        % Two-sided spectrum
        P2 = abs(Y/N);

        % Single-sided spectrum
        P1 = P2(1:floor(N/2)+1);

        if length(P1) > 2
            P1(2:end-1) = 2*P1(2:end-1);
        end

        % Frequency axis
        f = Fs*(0:floor(N/2))/N;

        % Clear axes
        cla(ax);

        % Plot
        plot(ax,f,P1,'LineWidth',1.2);

        grid(ax,'on');

        xlabel(ax,'Frequency (Hz)');
        ylabel(ax,'Magnitude');

    end


    % ==========================================
    % PSD PLOT FUNCTION
    % ==========================================
    function plotPSD(app, ax, SignalData)

        x = SignalData.signal;

        % Get sampling frequency
        if isfield(SignalData,'Fs') && ~isempty(SignalData.Fs)
            Fs = SignalData.Fs;
        else
            Fs = 1/(SignalData.time(2)-SignalData.time(1));
        end

        % Clear axes
        cla(ax);

        % Calculate and plot PSD
        [pxx,f] = pwelch(x,[],[],[],Fs);

        plot(ax,f,10*log10(pxx),'LineWidth',1.2);

        grid(ax,'on');

        xlabel(ax,'Frequency (Hz)');
        ylabel(ax,'Power/Frequency (dB/Hz)');

   
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GENERATESIGNALButton
        function GENERATESIGNALButtonPushed(app, event)
          

% Update Status
app.StatusReadyLabel.Text = "Status : Generating Signal...";

projectRoot = 'C:\Users\SAI VIJAY VARDHAN\OneDrive\ドキュメント\MATLAB\AI_Cognitive_Radio';

% Add entire project to MATLAB path
addpath(genpath(projectRoot));

% Make sure AI model folder is available
aiFolder = fullfile(projectRoot,'AI');
addpath(aiFolder);

rehash;
modelFile = fullfile(projectRoot,'AI','SavedModel.mat');

if ~isfile(modelFile)
    error('SavedModel.mat not found at: %s', modelFile);
end
% Get Selected Signal
signalType = app.SignalDropDown.Value;
% Generate Signal
switch signalType

    case 'BPSK'
        app.SignalData = generateBPSK();

    case 'QPSK'
        app.SignalData = generateQPSK();

    case 'AM'
        app.SignalData = generateAM();

    case 'FM'
        app.SignalData = generateFM();

    case 'QAM16'
        app.SignalData = generateQAM16();

    case 'OFDM'
        app.SignalData = generateOFDM();

    case 'Bluetooth'
        app.SignalData = generateBluetooth();

    case 'LTE'
        app.SignalData = generateLTE();

    case '5G'
        app.SignalData = generate5G();

end

% ===========================
% Update Parameter Box
% ===========================

app.PARAMETERBOXTextArea.Value = {
    ['Energy          : ' num2str(app.SignalData.energy)]
    ['Bandwidth       : ' num2str(app.SignalData.bandwidth)]
    ['Average Power   : ' num2str(app.SignalData.averagePower)]
    ['Peak Power      : ' num2str(app.SignalData.peakPower)]
    ['Peak Frequency  : ' num2str(app.SignalData.peakFrequency)]
    ['Noise Floor     : ' num2str(app.SignalData.noiseFloor)]
    ['SNR             : ' num2str(app.SignalData.SNR) ' dB']
};


%==========================================
% Plot Time Domain Signal
%==========================================

plot(app.UIAxes,...
     app.SignalData.time,...
     app.SignalData.signal,...
     'LineWidth',1.5);

grid(app.UIAxes,'on');

title(app.UIAxes,...
      [app.SignalData.signalName ' Time Domain']);

xlabel(app.UIAxes,'Time (s)');
ylabel(app.UIAxes,'Amplitude');
% Finished
app.StatusReadyLabel.Text = "Status : Signal Generated";

uialert(app.UIFigure,...
    [signalType ' Signal Generated Successfully'],...
    'Success');


        end

        % Button pushed function: PREDICTSIGNALButton
        function PREDICTSIGNALButtonPushed(app, event)
            %==========================================
% Predict Signal
%==========================================

if isempty(app.SignalData)

    uialert(app.UIFigure,...
        'Generate a signal first!',...
        'Error');

    return;

end

% Load AI model if needed
    if isempty(app.AIModel) || ...
       isempty(app.FeatureMin) || ...
       isempty(app.FeatureMax)

        loadModel(app);

    end

if isempty(app.AIModel)
    projectRoot = 'C:\Users\SAI VIJAY VARDHAN\OneDrive\ドキュメント\MATLAB\AI_Cognitive_Radio';
    load(fullfile(projectRoot,'AI','SavedModel.mat'),'Model');

    app.AIModel = Model;

end

% Prepare feature vector
featureVector = [
    app.SignalData.energy,...
    app.SignalData.averagePower,...
    app.SignalData.peakPower,...
    app.SignalData.peakToPeak,...
    app.SignalData.peakFrequency,...
    app.SignalData.bandwidth,...
    app.SignalData.noiseFloor,...
    app.SignalData.SNR
];

disp('==============================');
disp('APP RAW FEATURE VECTOR');
disp(featureVector);
disp('Size:');
disp(size(featureVector));
disp('==============================');
disp("Feature Vector Size:");
disp(size(featureVector));
disp("Feature Vector Length:");
disp(length(featureVector));
disp(featureVector);
% AI Prediction

disp('==============================');
disp('Signal Name:');
disp(app.SignalData.signalName);

disp('Feature Vector Size:');
disp(size(featureVector));

disp('Feature Vector Length:');
disp(length(featureVector));

disp('Feature Vector:');
disp(featureVector);

disp('FeatureVector field size:');
disp(size(app.SignalData.featureVector));

disp('NormalizedFeatures field size:');
disp(size(app.SignalData.normalizedFeatures));
disp('==============================');

disp("Prediction Input:");
disp(featureVector);
disp(size(featureVector));

disp("================================");
disp("APP PREDICTION TEST");
disp("Actual generated signal:");
disp(app.SignalData.signalName);
disp("Features sent to AI:");
disp(featureVector);
disp("================================");

% Normalize using the same parameters used during training
% ==========================================
% PREPARE FEATURE VECTOR
% ==========================================

featureVector = [...
    app.SignalData.energy,...
    app.SignalData.averagePower,...
    app.SignalData.peakPower,...
    app.SignalData.peakToPeak,...
    app.SignalData.peakFrequency,...
    app.SignalData.bandwidth,...
    app.SignalData.noiseFloor,...
    app.SignalData.SNR];

% Force everything to 1 x 8
featureVector = reshape(featureVector,1,[]);
featureMin = reshape(app.FeatureMin,1,[]);
featureMax = reshape(app.FeatureMax,1,[]);
disp('========== NORMALIZATION DEBUG ==========');

disp('Feature Vector:');
disp(featureVector);
disp(['Feature Vector size: ' num2str(size(featureVector))]);

disp('Feature Min:');
disp(app.FeatureMin);
disp(['Feature Min size: ' num2str(size(app.FeatureMin))]);

disp('Feature Max:');
disp(app.FeatureMax);
disp(['Feature Max size: ' num2str(size(app.FeatureMax))]);

disp('=========================================');
% ==========================================
% CHECK FEATURE SIZES
% ==========================================

disp('================ SIZE CHECK ================');
disp(['Feature Vector : ' num2str(size(featureVector))]);
disp(['Feature Min    : ' num2str(size(featureMin))]);
disp(['Feature Max    : ' num2str(size(featureMax))]);
disp('============================================');

if numel(featureVector) ~= numel(featureMin) || ...
   numel(featureVector) ~= numel(featureMax)

    uialert(app.UIFigure,...
        'Feature vector and normalization parameters have different sizes.',...
        'Feature Size Error');

    return;
end

% ==========================================
% NORMALIZATION
% ==========================================

featureRange = featureMax - featureMin;

featureRange(featureRange == 0) = 1;

normalizedFeatureVector = ...
    (featureVector - featureMin) ./ featureRange;

disp('Normalized Feature Vector:');
disp(normalizedFeatureVector);

% ==========================================
% AI PREDICTION
% ==========================================

[predictedLabel,scores] = predict(...
    app.AIModel,...
    normalizedFeatureVector);
if isfield(app.SignalData,'normalizedFeatures')
    disp("NORMALIZED FEATURE VECTOR:");
    disp(app.SignalData.normalizedFeatures);
end
% Find the highest prediction score
confidence = max(scores);
% UPDATE PREDICTION PANEL
% ==========================================

app.PredictionTextArea.Value = {
    ['Predicted Signal : ' char(predictedLabel)]
      ['Confidence       : ' num2str(confidence*100,'%.2f') ' %']
    ['Status           : ' char(app.SignalData.channelStatus)]
    ['Channel          : ' char(app.SignalData.recommendedChannel)]
};
disp('Predicted Label:');
disp(predictedLabel);

disp('Prediction Scores:');
disp(scores);

disp('Score Size:');
disp(size(scores));

disp("RAW FEATURE VECTOR:");
disp(featureVector);

% Update status
app.StatusReadyLabel.Text = "Status : Prediction Completed";
        end

        % Button pushed function: SHOWGRAPHButton
        function SHOWGRAPHButtonPushed(app, event)

    % CHECK SIGNAL
    if isempty(app.SignalData)
        uialert(app.UIFigure, ...
            'Generate a signal first!', ...
            'No Signal');
        return;
    end
    % GET SELECTED GRAPHS
    selectedGraphs = app.GRAPHLISTBOXListBox.Value;

    if isempty(selectedGraphs)
        uialert(app.UIFigure, ...
            'Please select at least one graph.', ...
            'No Graph Selected');
        return;
    end

    % Make sure selectedGraphs is a cell array
    if ischar(selectedGraphs)
        selectedGraphs = {selectedGraphs};
    elseif isstring(selectedGraphs)
        selectedGraphs = cellstr(selectedGraphs);
    end

  
    % CLEAR AXES
 

    cla(app.UIAxes);
    cla(app.UIAxes2);

   
    % GET SIGNAL DATA
  

    x = app.SignalData.signal;
    t = app.SignalData.time;

    x = x(:).';


    % SAMPLING FREQUENCY
   

    if isfield(app.SignalData,'Fs') && ...
            ~isempty(app.SignalData.Fs)

        Fs = app.SignalData.Fs;

    else

        Fs = 1/(t(2)-t(1));

    end

    signalName = app.SignalData.signalName;
  % MAXIMUM 2 GRAPHS
    numberOfGraphs = min(length(selectedGraphs),2);
  % GRAPH 1
    if numberOfGraphs >= 1

        graphType = strtrim(selectedGraphs{1});

        if strcmpi(graphType,'Time Signal')
            displayTime = 0.2e-3;   % 0.2 ms

          N = min(length(x), round(displayTime * Fs));

         plot(app.UIAxes, t(1:N), x(1:N), ...
        'LineWidth', 1.2);

         grid(app.UIAxes, 'on');

         title(app.UIAxes, ...
        [signalName ' - Time Domain']);

        xlabel(app.UIAxes, 'Time (s)');
          ylabel(app.UIAxes, 'Amplitude');

        axis(app.UIAxes, 'tight');
        elseif strcmpi(graphType,'FFT')

            N = length(x);

            Y = fft(x);

            P2 = abs(Y/N);

            P1 = P2(1:floor(N/2)+1);

            if length(P1) > 2
                P1(2:end-1) = ...
                    2*P1(2:end-1);
            end

            f = Fs*(0:floor(N/2))/N;

            plot(app.UIAxes,f,P1,'LineWidth',1.2);
            grid(app.UIAxes,'on');

            title(app.UIAxes,...
                [signalName ' - FFT']);

            xlabel(app.UIAxes,'Frequency (Hz)');
            ylabel(app.UIAxes,'Magnitude');

        elseif strcmpi(graphType,'PSD')

            try

                [pxx,f] = pwelch(x,[],[],[],Fs);

                plot(app.UIAxes,...
                    f,...
                    10*log10(pxx),...
                    'LineWidth',1.2);

                grid(app.UIAxes,'on');

                title(app.UIAxes,...
                    [signalName ' - Power Spectral Density']);

                xlabel(app.UIAxes,'Frequency (Hz)');
                ylabel(app.UIAxes,'Power/Frequency (dB/Hz)');

            catch

                uialert(app.UIFigure,...
                    'PSD calculation failed.',...
                    'PSD Error');

            end

        elseif strcmpi(graphType,'Constellation')

            if isfield(app.SignalData,'symbols') && ...
                    ~isempty(app.SignalData.symbols)

                symbols = app.SignalData.symbols;

                plot(app.UIAxes,...
                    real(symbols),...
                    imag(symbols),...
                    'o',...
                    'MarkerSize',6);

                grid(app.UIAxes,'on');

                axis(app.UIAxes,'equal');

                title(app.UIAxes,...
                    [signalName ' - Constellation']);

                xlabel(app.UIAxes,'In-Phase (I)');
                ylabel(app.UIAxes,'Quadrature (Q)');

            else

                text(app.UIAxes,...
                    0.5,0.5,...
                    'Constellation data not available',...
                    'HorizontalAlignment','center');

                axis(app.UIAxes,'off');

            end

       elseif strcmpi(graphType,'Spectrum Occupancy')

    % Clear Graph 1
    cla(app.UIAxes);

    % Check whether channel information exists
    if isfield(app.SignalData,'channelOccupied') && ...
            isfield(app.SignalData,'numChannels')

        % Get channel occupancy information
        channelOccupied = app.SignalData.channelOccupied;
        numChannels = app.SignalData.numChannels;

        % Convert logical values to 0 and 1
        occupancy = double(channelOccupied);

        % Draw the 5-channel bar graph
        bar(app.UIAxes,1:numChannels,occupancy);

        % Grid
        grid(app.UIAxes,'on');

        % Y-axis range
        ylim(app.UIAxes,[-0.1 1.2]);

        % X-axis range
        xlim(app.UIAxes,[0.5 numChannels+0.5]);

        % X-axis ticks
        xticks(app.UIAxes,1:numChannels);

        % X-axis labels
        xticklabels(app.UIAxes,{...
            'Channel 1',...
            'Channel 2',...
            'Channel 3',...
            'Channel 4',...
            'Channel 5'});

        % Y-axis ticks
        yticks(app.UIAxes,[0 1]);

        % Y-axis labels
        yticklabels(app.UIAxes,...
            {'FREE','OCCUPIED'});

        % Axis labels
        xlabel(app.UIAxes,'Channel');
        ylabel(app.UIAxes,'Occupancy');

        % Title
        title(app.UIAxes,...
            [signalName ' - 5-Channel Spectrum Occupancy']);

        % ==========================================
        % WRITE FREE / OCCUPIED ON EACH BAR
        % ==========================================

        for k = 1:numChannels

            if channelOccupied(k)

                text(app.UIAxes,...
                    k,1.05,...
                    'OCCUPIED',...
                    'HorizontalAlignment','center',...
                    'FontWeight','bold');

            else

                text(app.UIAxes,...
                    k,0.05,...
                    'FREE',...
                    'HorizontalAlignment','center',...
                    'FontWeight','bold');

            end

        end

        % ==========================================
        % RECOMMENDED CHANNEL
        % ==========================================

        if isfield(app.SignalData,'recommendedChannel')

            subtitle(app.UIAxes,...
                ['Recommended Channel : ' ...
                char(app.SignalData.recommendedChannel)]);

        end

    else

        text(app.UIAxes,...
            0.5,0.5,...
            '5-channel occupancy data not available',...
            'HorizontalAlignment','center');

        axis(app.UIAxes,'off');

    end
        end
        end

    
% GRAPH 2
    if numberOfGraphs >= 2

        graphType = strtrim(selectedGraphs{2});

        if strcmpi(graphType,'Time Signal')

            plot(app.UIAxes2,t,x,'LineWidth',1.2);
            grid(app.UIAxes2,'on');

            title(app.UIAxes2,...
                [signalName ' - Time Domain']);

            xlabel(app.UIAxes2,'Time (s)');
            ylabel(app.UIAxes2,'Amplitude');

        elseif strcmpi(graphType,'FFT')

            N = length(x);

            Y = fft(x);

            P2 = abs(Y/N);

            P1 = P2(1:floor(N/2)+1);

            if length(P1) > 2
                P1(2:end-1) = ...
                    2*P1(2:end-1);
            end

            f = Fs*(0:floor(N/2))/N;

            plot(app.UIAxes2,f,P1,'LineWidth',1.2);
            grid(app.UIAxes2,'on');

            title(app.UIAxes2,...
                [signalName ' - FFT']);

            xlabel(app.UIAxes2,'Frequency (Hz)');
            ylabel(app.UIAxes2,'Magnitude');

        elseif strcmpi(graphType,'PSD')

            try

                [pxx,f] = pwelch(x,[],[],[],Fs);

                plot(app.UIAxes2,...
                    f,...
                    10*log10(pxx),...
                    'LineWidth',1.2);

                grid(app.UIAxes2,'on');

                title(app.UIAxes2,...
                    [signalName ' - Power Spectral Density']);

                xlabel(app.UIAxes2,'Frequency (Hz)');
                ylabel(app.UIAxes2,...
                    'Power/Frequency (dB/Hz)');

            catch

                uialert(app.UIFigure,...
                    'PSD calculation failed.',...
                    'PSD Error');

            end

        elseif strcmpi(graphType,'Constellation')

            if isfield(app.SignalData,'symbols') && ...
                    ~isempty(app.SignalData.symbols)

                symbols = app.SignalData.symbols;

                plot(app.UIAxes2,...
                    real(symbols),...
                    imag(symbols),...
                    'o',...
                    'MarkerSize',6);

                grid(app.UIAxes2,'on');

                axis(app.UIAxes2,'equal');

                title(app.UIAxes2,...
                    [signalName ' - Constellation']);

                xlabel(app.UIAxes2,'In-Phase (I)');
                ylabel(app.UIAxes2,'Quadrature (Q)');

            else

                text(app.UIAxes2,...
                    0.5,0.5,...
                    'Constellation data not available',...
                    'HorizontalAlignment','center');

                axis(app.UIAxes2,'off');

            end

        elseif strcmpi(graphType,'Spectrum Occupancy')

    % FFT
    N = length(x);
    Y = fft(x);
    P2 = abs(Y/N);

    P1 = P2(1:floor(N/2)+1);

    if length(P1) > 2
        P1(2:end-1) = 2*P1(2:end-1);
    end

    f = Fs*(0:floor(N/2))/N;

    % Threshold
    threshold = 0.2 * max(P1);

    % Occupancy
    occupied = P1 >= threshold;

    % Plot
    stairs(app.UIAxes2,...
        f,...
        double(occupied),...
        'LineWidth',1.5);

    grid(app.UIAxes2,'on');

    ylim(app.UIAxes2,[-0.1 1.1]);

    title(app.UIAxes2,...
        [signalName ' - Spectrum Occupancy']);

    xlabel(app.UIAxes2,'Frequency (Hz)');
    ylabel(app.UIAxes2,'Occupancy');

    yticks(app.UIAxes2,[0 1]);

    yticklabels(app.UIAxes2,...
        {'Unoccupied','Occupied'});

    % Show threshold
    hold(app.UIAxes2,'on');

    yline(app.UIAxes2,...
        0.5,...
        '--',...
        'Occupied Region');

    hold(app.UIAxes2,'off');


        end

    end

    % STATUS
    app.StatusReadyLabel.Text = ...
        "Status : Graph Displayed";


        end

        % Button pushed function: COMPARESIGNALSButton
        function COMPARESIGNALSButtonPushed(app, event)
          
    % ==========================================
    % CHECK SIGNAL SELECTION
    % ==========================================

    signal1Name = app.COMPARESIGNAL1DropDown.Value;
    signal2Name = app.COMPARESIGNAL2DropDown.Value;
    compareType = app.COMPARETYPEDropDown.Value;

    % ==========================================
    % GENERATE SIGNAL 1 &2
    % ==========================================

    signal1 = generateSelectedSignal(app, signal1Name);
    signal2 = generateSelectedSignal(app, signal2Name);
    % ==========================================
    % CLEAR AXES
    % ==========================================

    cla(app.UIAxes);
    cla(app.UIAxes2);

    % ==========================================
    % COMPARE TIME SIGNAL
    % ==========================================

    if strcmpi(strtrim(compareType),'Time Signal')

        plot(app.UIAxes,...
            signal1.time,...
            signal1.signal,...
            'LineWidth',1.2);

        grid(app.UIAxes,'on');

        title(app.UIAxes,...
            [signal1Name ' - Time Signal']);

        xlabel(app.UIAxes,'Time (s)');
        ylabel(app.UIAxes,'Amplitude');


        plot(app.UIAxes2,...
            signal2.time,...
            signal2.signal,...
            'LineWidth',1.2);

        grid(app.UIAxes2,'on');

        title(app.UIAxes2,...
            [signal2Name ' - Time Signal']);

        xlabel(app.UIAxes2,'Time (s)');
        ylabel(app.UIAxes2,'Amplitude');

    % ==========================================
    % COMPARE FFT
    % ==========================================

    elseif strcmpi(strtrim(compareType),'FFT')

        plotFFT(app, app.UIAxes,signal1);
        title(app.UIAxes,[signal1Name ' - FFT']);

        plotFFT(app, app.UIAxes2,signal2);
        title(app.UIAxes2,[signal2Name ' - FFT']);

    % ==========================================
    % COMPARE PSD
    % ==========================================

    elseif strcmpi(strtrim(compareType),'PSD')

        plotPSD(app, app.UIAxes,signal1);
        title(app.UIAxes,[signal1Name ' - PSD']);

        plotPSD(app, app.UIAxes2,signal2);
        title(app.UIAxes2,[signal2Name ' - PSD']);

    % ==========================================
    % COMPARE CONSTELLATION
    % ==========================================

    elseif strcmpi(strtrim(compareType),'Constellation')

        if isfield(signal1,'symbols') && ~isempty(signal1.symbols)

            plot(app.UIAxes,...
                real(signal1.symbols),...
                imag(signal1.symbols),...
                'o',...
                'MarkerSize',7);

            grid(app.UIAxes,'on');
            axis(app.UIAxes,'equal');

            title(app.UIAxes,...
                [signal1Name ' - Constellation']);

            xlabel(app.UIAxes,'In-Phase (I)');
            ylabel(app.UIAxes,'Quadrature (Q)');

        else

            text(app.UIAxes,...
                0.5,0.5,...
                'Constellation data not available',...
                'HorizontalAlignment','center');

            axis(app.UIAxes,'off');

        end


        if isfield(signal2,'symbols') && ~isempty(signal2.symbols)

            plot(app.UIAxes2,...
                real(signal2.symbols),...
                imag(signal2.symbols),...
                'o',...
                'MarkerSize',7);

            grid(app.UIAxes2,'on');
            axis(app.UIAxes2,'equal');

            title(app.UIAxes2,...
                [signal2Name ' - Constellation']);

            xlabel(app.UIAxes2,'In-Phase (I)');
            ylabel(app.UIAxes2,'Quadrature (Q)');

        else

            text(app.UIAxes2,...
                0.5,0.5,...
                'Constellation data not available',...
                'HorizontalAlignment','center');

            axis(app.UIAxes2,'off');

        end
       
% COMPARE SPECTRUM OCCUPANCY
elseif strcmpi(strtrim(compareType),'Spectrum Occupancy')

    % ---------- SIGNAL 1 ----------

    x = signal1.signal;
    Fs = signal1.Fs;

    N = length(x);

    Y = fft(x);

    P2 = abs(Y/N);

    P1 = P2(1:floor(N/2)+1);

    if length(P1) > 2
        P1(2:end-1) = 2*P1(2:end-1);
    end

    f = Fs*(0:floor(N/2))/N;

    threshold = 0.2 * max(P1);

    occupied = P1 >= threshold;

    stairs(app.UIAxes,...
        f,...
        double(occupied),...
        'LineWidth',1.5);

    grid(app.UIAxes,'on');

    ylim(app.UIAxes,[-0.1 1.1]);

    title(app.UIAxes,...
        [signal1Name ' - Spectrum Occupancy']);

    xlabel(app.UIAxes,'Frequency (Hz)');
    ylabel(app.UIAxes,'Occupancy');

    yticks(app.UIAxes,[0 1]);

    yticklabels(app.UIAxes,...
        {'Unoccupied','Occupied'});


    % ---------- SIGNAL 2 ----------

    x = signal2.signal;
    Fs = signal2.Fs;

    N = length(x);

    Y = fft(x);

    P2 = abs(Y/N);

    P1 = P2(1:floor(N/2)+1);

    if length(P1) > 2
        P1(2:end-1) = 2*P1(2:end-1);
    end

    f = Fs*(0:floor(N/2))/N;

    threshold = 0.2 * max(P1);

    occupied = P1 >= threshold;

    stairs(app.UIAxes2,...
        f,...
        double(occupied),...
        'LineWidth',1.5);

    grid(app.UIAxes2,'on');

    ylim(app.UIAxes2,[-0.1 1.1]);

    title(app.UIAxes2,...
        [signal2Name ' - Spectrum Occupancy']);

    xlabel(app.UIAxes2,'Frequency (Hz)');
    ylabel(app.UIAxes2,'Occupancy');

    yticks(app.UIAxes2,[0 1]);

    yticklabels(app.UIAxes2,...
        {'Unoccupied','Occupied'});

    end
    % STATUS
    app.StatusReadyLabel.Text = ...
        "Status : Signals Compared";


        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)


    % Clear generated signal data
    app.SignalData = [];

    % Clear graphs
    cla(app.UIAxes);
    cla(app.UIAxes2);

    % Reset graph titles
    title(app.UIAxes,'UI AXES MAIN');
    title(app.UIAxes2,'UI AXES COMPARE');

    % Reset parameter box
    app.PARAMETERBOXTextArea.Value = {
        'Energy          :';
        'Bandwidth       :';
        'Average Power   :';
        'Peak Power      :';
        'Peak Frequency  :';
        'Noise Floor     :';
        'SNR             :'
    };

    % Reset prediction box
    app.PredictionTextArea.Value = {
        'Predicted Signal : No Prediction';
        'Confidence       : --';
        'Status           : --';
        'Channel          : --'
    };

    % Reset signal selection
    app.SignalDropDown.Value = 'BPSK';

    % Reset graph selection
    app.GRAPHLISTBOXListBox.Value = {'Time Signal'};

    % Reset comparison selections
    app.COMPARESIGNAL1DropDown.Value = 'BPSK';
    app.COMPARESIGNAL2DropDown.Value = 'BPSK';
    app.COMPARETYPEDropDown.Value = 'Time Signal';

    % Reset status
    app.StatusReadyLabel.Text = 'Status : Ready';

        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
             % Ask for confirmation
    choice = uiconfirm(app.UIFigure, ...
        'Are you sure you want to exit the Cognitive Radio System?', ...
        'Exit Confirmation', ...
        'Options', {'Yes', 'No'}, ...
        'DefaultOption', 'No', ...
        'CancelOption', 'No');

    % Close application if Yes
    if strcmp(choice, 'Yes')
        delete(app);
    end

        end

        % Button pushed function: SCANButton
        function SCANButtonPushed(app, event)
technology = strtrim(app.TechnologyDropDown.Value);
% WI-FI SCAN
% ==========================================
if strcmpi(technology,'Wi-Fi')
app.StatusReadyLabel.Text = "Status : Scanning Wi-Fi...";

drawnow;

try

    % --------------------------------------
    % Scan nearby Wi-Fi networks
    % --------------------------------------

    wifiData = scanWiFi();

    if isempty(wifiData)

        app.AIRECOMMENDATIONTextArea.Value = ...
            "No Wi-Fi networks detected.";

        app.UITable.Data = {};

        app.StatusReadyLabel.Text = ...
            "Status : No Networks Found";

        return;

    end

    % --------------------------------------
    % Number of detected networks
    % --------------------------------------

    N = length(wifiData);

    % --------------------------------------
    % Prepare table
    % --------------------------------------

    tableData = cell(N,5);

    % --------------------------------------
    % Calculate quality
    % --------------------------------------

    qualityScore = zeros(N,1);

    for k = 1:N

        % Signal strength
        signal = wifiData(k).Signal;

        % Channel utilization
        utilization = wifiData(k).ChannelUtilization;

        % ----------------------------------
        % Quality score
        % ----------------------------------

        if isnan(utilization)

            % If utilization is unavailable,
            % use signal strength only

            score = signal;

        else

            % Strong signal + low utilization

            score = ...
                0.7*signal + ...
                0.3*(100-utilization);

        end

        % Limit score to 0-100

        score = max(0,min(100,score));

        qualityScore(k) = score;

        % ----------------------------------
        % Quality text
        % ----------------------------------

        if score >= 80

            qualityText = ...
                "Excellent (" + round(score) + "/100)";

        elseif score >= 60

            qualityText = ...
                "Good (" + round(score) + "/100)";

        elseif score >= 40

            qualityText = ...
                "Fair (" + round(score) + "/100)";

        else

            qualityText = ...
                "Poor (" + round(score) + "/100)";

        end

        % ----------------------------------
        % Fill table
        % ----------------------------------

        tableData{k,1} = char(wifiData(k).SSID);

        tableData{k,2} = char(wifiData(k).Band);

        tableData{k,3} = wifiData(k).Channel;

        tableData{k,4} = ...
        [num2str(wifiData(k).Signal) '%'];

        tableData{k,5} = char(qualityText);

    end

    % --------------------------------------
    % Display table
    % --------------------------------------

    app.UITable.ColumnName = ...
        {'SSID','Band','Channel','Signal','Quality'};

    app.UITable.Data = tableData;

    % --------------------------------------
    % Find best network
    % --------------------------------------

    [bestScore,bestIndex] = max(qualityScore);

    bestSSID = wifiData(bestIndex).SSID;
    bestBand = wifiData(bestIndex).Band;
    bestChannel = wifiData(bestIndex).Channel;
    bestSignal = wifiData(bestIndex).Signal;

    % --------------------------------------
    % Recommendation
    % --------------------------------------

    recommendation = [
        "Technology : Wi-Fi"
        ""
        "Recommended Network : " + bestSSID
        "Channel : " + string(bestChannel)
        "Band : " + bestBand
        "Signal : " + string(bestSignal) + "%"
        "Quality Score : " + string(round(bestScore)) + "/100"
        ""
        "Reason : Strong signal and better channel conditions."
    ];

    app.AIRECOMMENDATIONTextArea.Value = recommendation;

    % --------------------------------------
    % Status
    % --------------------------------------

    app.StatusReadyLabel.Text = ...
        "Status : Wi-Fi Scan Completed";

catch ME

    app.StatusReadyLabel.Text = ...
        "Status : Wi-Fi Scan Failed";

    app.AIRECOMMENDATIONTextArea.Value = ...
        "Wi-Fi scan error:" + newline + ME.message;
end
    % BLUETOOTH SCAN
% ==========================================

elseif strcmpi(technology,'Bluetooth')

    app.StatusReadyLabel.Text = ...
        "Status : Scanning Bluetooth...";

    drawnow;

    try

        % ----------------------------------
        % Scan nearby BLE devices
        % ----------------------------------

        bluetoothData = scanBluetooth();

        if isempty(bluetoothData)

            app.UITable.Data = {};

            app.AIRECOMMENDATIONTextArea.Value = ...
                "No nearby Bluetooth/BLE devices detected.";

            app.StatusReadyLabel.Text = ...
                "Status : No Bluetooth Devices";

            return;

        end

        % ----------------------------------
        % NUMBER OF DEVICES
        % ----------------------------------

        N = length(bluetoothData);

        % ----------------------------------
        % TABLE
        % ----------------------------------

        tableData = cell(N,4);

        scores = zeros(N,1);

        for k = 1:N

            tableData{k,1} = ...
                char(bluetoothData(k).Name);

            tableData{k,2} = ...
                char(bluetoothData(k).Address);

            tableData{k,3} = ...
                bluetoothData(k).RSSI;

            tableData{k,4} = ...
                char(bluetoothData(k).Quality);

            scores(k) = ...
                bluetoothData(k).Score;

        end

        % ----------------------------------
        % DISPLAY TABLE
        % ----------------------------------

        app.UITable.ColumnName = ...
            {'Device','Address','RSSI (dBm)','Quality'};

        app.UITable.Data = tableData;

        % ----------------------------------
        % FIND BEST DEVICE
        % ----------------------------------

        [bestScore,bestIndex] = max(scores);

        bestName = ...
            bluetoothData(bestIndex).Name;

        bestAddress = ...
            bluetoothData(bestIndex).Address;

        bestRSSI = ...
            bluetoothData(bestIndex).RSSI;

        bestQuality = ...
            bluetoothData(bestIndex).Quality;

        % ----------------------------------
        % AI RECOMMENDATION
        % ----------------------------------

        recommendation = [
            "Technology : Bluetooth/BLE"
            ""
            "Recommended Device : " + string(bestName)
            "Address : " + string(bestAddress)
            "RSSI : " + string(bestRSSI) + " dBm"
            "Quality : " + string(bestQuality)
            "Quality Score : " + string(round(bestScore)) + "/100"
            ""
            "Reason : Strongest detected BLE signal."
        ];

        app.AIRECOMMENDATIONTextArea.Value = ...
            recommendation;

        % ----------------------------------
        % STATUS
        % ----------------------------------

        app.StatusReadyLabel.Text = ...
            "Status : Bluetooth Scan Completed";

    catch ME

        app.StatusReadyLabel.Text = ...
            "Status : Bluetooth Scan Failed";

        app.AIRECOMMENDATIONTextArea.Value = [
            "Bluetooth scan error:"
            ""
            string(ME.message)
        ];

    end


        end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AICONGNITIVERADIOSYSTEMLabel = uilabel(app.UIFigure);
            app.AICONGNITIVERADIOSYSTEMLabel.Text = 'AI CONGNITIVE RADIO SYSTEM';
            app.SIGNALTYPEPanel = uipanel(app.UIFigure);
            app.SIGNALTYPEPanel.Title = 'SIGNAL TYPE';
            app.SignalDropDownLabel = uilabel(app.SIGNALTYPEPanel);
            app.SignalDropDownLabel.Text = 'SignalDropDown';
            app.SignalDropDown = uidropdown(app.SIGNALTYPEPanel);
            app.SignalDropDown.Items = {'BPSK', 'QPSK', 'AM', 'FM', 'QAM16', 'OFDM', 'Bluetooth', 'LTE', '5G'};
            app.SignalDropDown.Value = 'BPSK';
            app.GENERATESIGNALButton = uibutton(app.SIGNALTYPEPanel, 'push');
            app.GENERATESIGNALButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATESIGNALButtonPushed, true);
            app.GENERATESIGNALButton.Text = 'GENERATE SIGNAL';
            app.PREDICTSIGNALButton = uibutton(app.SIGNALTYPEPanel, 'push');
            app.PREDICTSIGNALButton.ButtonPushedFcn = createCallbackFcn(app, @PREDICTSIGNALButtonPushed, true);
            app.PREDICTSIGNALButton.Text = 'PREDICT SIGNAL';
            app.DISPLAYGRAPHPanel = uipanel(app.UIFigure);
            app.DISPLAYGRAPHPanel.Title = 'DISPLAY GRAPH';
            app.Label = uilabel(app.DISPLAYGRAPHPanel);
            app.Label.Text = 'GRAPH LIST BOX';
            app.GRAPHLISTBOXListBox = uilistbox(app.DISPLAYGRAPHPanel);
            app.GRAPHLISTBOXListBox.Items = {'Time Signal', 'FFT', 'PSD', 'Constellation', 'Spectrum Occupancy'};
            app.GRAPHLISTBOXListBox.Multiselect = 'on';
            app.GRAPHLISTBOXListBox.Value = {'Time Signal'};
            app.SHOWGRAPHButton = uibutton(app.DISPLAYGRAPHPanel, 'push');
            app.SHOWGRAPHButton.ButtonPushedFcn = createCallbackFcn(app, @SHOWGRAPHButtonPushed, true);
            app.SHOWGRAPHButton.Text = 'SHOW GRAPH';
            app.SIGNALPARAMETERPanel = uipanel(app.UIFigure);
            app.SIGNALPARAMETERPanel.Title = 'SIGNAL PARAMETER';
            app.PARAMETERBOXTextAreaLabel = uilabel(app.SIGNALPARAMETERPanel);
            app.PARAMETERBOXTextAreaLabel.Text = {'PARAMETER '; 'BOX'};
            app.PARAMETERBOXTextArea = uitextarea(app.SIGNALPARAMETERPanel);
            app.PARAMETERBOXTextArea.Value = {'Energy          :'; 'Bandwidth       :'; 'Average Power   :'; 'Peak Power      :'; 'Peak Frequency  :'; 'Noise Floor     :'; 'SNR             :'};
            app.COMPARESIGNALSPanel = uipanel(app.UIFigure);
            app.COMPARESIGNALSPanel.Title = 'COMPARE SIGNALS';
            app.COMPARESIGNAL1DropDownLabel = uilabel(app.COMPARESIGNALSPanel);
            app.COMPARESIGNAL1DropDownLabel.Text = {' COMPARE'; 'SIGNAL'; '1'};
            app.COMPARESIGNAL1DropDown = uidropdown(app.COMPARESIGNALSPanel);
            app.COMPARESIGNAL1DropDown.Items = {'BPSK', 'QPSK', 'AM', 'FM', 'QAM16', 'OFDM', 'Bluetooth', 'LTE', '5G'};
            app.COMPARESIGNAL1DropDown.Value = 'BPSK';
            app.COMPARESIGNAL2DropDownLabel = uilabel(app.COMPARESIGNALSPanel);
            app.COMPARESIGNAL2DropDownLabel.Text = {'COMPARE'; 'SIGNAL '; '2'};
            app.COMPARESIGNAL2DropDown = uidropdown(app.COMPARESIGNALSPanel);
            app.COMPARESIGNAL2DropDown.Items = {'BPSK', 'QPSK', 'AM', 'FM', 'QAM16', 'OFDM', 'Bluetooth', 'LTE', '5G'};
            app.COMPARESIGNAL2DropDown.Value = 'BPSK';
            app.COMPARESIGNALSButton = uibutton(app.COMPARESIGNALSPanel, 'push');
            app.COMPARESIGNALSButton.ButtonPushedFcn = createCallbackFcn(app, @COMPARESIGNALSButtonPushed, true);
            app.COMPARESIGNALSButton.Text = 'COMPARE SIGNALS';
            app.COMPARETYPELabel = uilabel(app.COMPARESIGNALSPanel);
            app.COMPARETYPELabel.Text = {'COMPARE '; 'TYPE'};
            app.COMPARETYPEDropDown = uidropdown(app.COMPARESIGNALSPanel);
            app.COMPARETYPEDropDown.Items = {'Time Signal', 'FFT', 'PSD', 'Constellation ', 'Spectrum Occupancy'};
            app.COMPARETYPEDropDown.Value = 'Time Signal';
            app.AIPREDICTIONPanel = uipanel(app.UIFigure);
            app.AIPREDICTIONPanel.Title = 'AI PREDICTION';
            app.PREDICTIONRESULTLabel = uilabel(app.AIPREDICTIONPanel);
            app.PREDICTIONRESULTLabel.Text = 'PREDICTION RESULT';
            app.PredictionTextAreaLabel = uilabel(app.AIPREDICTIONPanel);
            app.PredictionTextAreaLabel.Text = {'Prediction'; ' Text Area'};
            app.PredictionTextArea = uitextarea(app.AIPREDICTIONPanel);
            app.PredictionTextArea.Editable = 'off';
            app.PredictionTextArea.Value = {'Predicted Signal : No Prediction'; 'Confidence       : --'; 'Status           : --'; 'Channel          : --'};
            app.SignalVisualizationPanel = uipanel(app.UIFigure);
            app.SignalVisualizationPanel.Title = 'Signal Visualization';
            app.UIAxes = uiaxes(app.SignalVisualizationPanel);
            title(app.UIAxes, 'UI AXES MAIN')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.SignalVisualizationPanel);
            title(app.UIAxes2, 'UI AXES COMPARE')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RESETButton = uibutton(app.SignalVisualizationPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.StatusReadyLabel = uilabel(app.UIFigure);
            app.StatusReadyLabel.Text = 'Status : Ready';
            app.TechnologyPanel = uipanel(app.UIFigure);
            app.TechnologyPanel.Title = 'Technology:';
            app.TechnologyDropDownLabel = uilabel(app.TechnologyPanel);
            app.TechnologyDropDownLabel.Text = 'TechnologyDrop Down';
            app.TechnologyDropDown = uidropdown(app.TechnologyPanel);
            app.TechnologyDropDown.Items = {'Wi-Fi', 'Hotspot', 'Bluetooth', 'LTE', '5G'};
            app.TechnologyDropDown.Value = 'Wi-Fi';
            app.SCANButton = uibutton(app.TechnologyPanel, 'push');
            app.SCANButton.ButtonPushedFcn = createCallbackFcn(app, @SCANButtonPushed, true);
            app.SCANButton.Text = 'SCAN';
            app.UITable = uitable(app.TechnologyPanel);
            app.UITable.ColumnName = {'SSID'; 'Band'; 'Channel'; 'Signal'; 'Quality'};
            app.UITable.RowName = {};
            app.AIRECOMMENDATIONTextAreaLabel = uilabel(app.TechnologyPanel);
            app.AIRECOMMENDATIONTextAreaLabel.Text = 'AI RECOMMENDATION';
            app.AIRECOMMENDATIONTextArea = uitextarea(app.TechnologyPanel);
            app.AIRECOMMENDATIONTextArea.Value = {'Recommended Channel :-'; 'Quality Score :-'; 'Reason :-'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CognitiveRadioDashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end